classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Lamp                matlab.ui.control.Lamp
        EditField           matlab.ui.control.EditField
        ResultEditField     matlab.ui.control.EditField
        ShowResultButton    matlab.ui.control.Button
        MinutiaeButton      matlab.ui.control.Button
        ThinnedImageButton  matlab.ui.control.Button
        BinarizationButton  matlab.ui.control.Button
        EnhancementButton   matlab.ui.control.Button
        Inputimage2Button   matlab.ui.control.Button
        Inputimage1Button   matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Inputimage1Button
        function inpimg1(app, event)
            global Img;

            [filename, pathname] = uigetfile('*', 'Select an image?');
            
            filename = strcat(pathname,filename);
            
            Img = imread(filename);

            imshow(Img,'Parent', app.UIAxes);
        end

        % Button pushed function: Inputimage2Button
        function inpimg2(app, event)
            global Img2;

            [filename, pathname] = uigetfile('*', 'Select an image?');
            
            filename = strcat(pathname,filename);
            
            Img2 = imread(filename);

            imshow(Img2,'Parent', app.UIAxes2);
        end

        % Button pushed function: EnhancementButton
        function enhancement(app, event)
            global Img;
            global Img2;
            global final1;
            global final2;

            final1=image_enhancement(Img,0.45);
            final2=image_enhancement(Img2,0.45);

            imshow(final1,'Parent', app.UIAxes);
            imshow(final2,'Parent', app.UIAxes2);
        end

        % Button pushed function: BinarizationButton
        function binar(app, event)
            global final1;
            global final2; 
            global final12;
            global final13; 

            final12=image_binarization(final1,16);
            final13=image_binarization(final2,16);

            imshow(final12,'Parent', app.UIAxes);
            imshow(final13,'Parent', app.UIAxes2);
        end

        % Button pushed function: ThinnedImageButton
        function thin(app, event)
            global final12;
            global thin_image1;
            thin_image1=~bwmorph(final12,'thin',Inf);
            imshow(thin_image1,'Parent',app.UIAxes);

            global final13;
            global thin_image2;
            thin_image2=~bwmorph(final13,'thin',Inf);
            imshow(thin_image2,'Parent',app.UIAxes2);
        end

        % Button pushed function: MinutiaeButton
        function minut(app, event)
            global thin_image1;
            global Mi;
            Mi=minutia_extract(thin_image1);
            imshow(Mi,'Parent',app.UIAxes);

            global thin_image2;
            global Mi2;
            Mi2=minutia_extract(thin_image2);
            imshow(Mi2,'Parent',app.UIAxes2);
        end

        % Button pushed function: ShowResultButton
        function result(app, event)
            global Mi;
            global Mi2;

            hehe=100*match(Mi,Mi2);
%             if hehe==100
            if hehe>=90 && hehe<=100
                app.ResultEditField.Value = 'Matched';
                app.Lamp.Color = [0.00,1.00,0.00];
            else
                app.ResultEditField.Value = 'Did not match';
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image-1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Image-2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Inputimage1Button = uibutton(app.UIFigure, 'push');
            app.Inputimage1Button.ButtonPushedFcn = createCallbackFcn(app, @inpimg1, true);
            app.Inputimage1Button.Text = 'Input image 1';
            app.Inputimage2Button = uibutton(app.UIFigure, 'push');
            app.Inputimage2Button.ButtonPushedFcn = createCallbackFcn(app, @inpimg2, true);
            app.Inputimage2Button.Text = 'Input image 2';
            app.EnhancementButton = uibutton(app.UIFigure, 'push');
            app.EnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @enhancement, true);
            app.EnhancementButton.Text = 'Enhancement';
            app.BinarizationButton = uibutton(app.UIFigure, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @binar, true);
            app.BinarizationButton.Text = 'Binarization';
            app.ThinnedImageButton = uibutton(app.UIFigure, 'push');
            app.ThinnedImageButton.ButtonPushedFcn = createCallbackFcn(app, @thin, true);
            app.ThinnedImageButton.Text = 'Thinned Image';
            app.MinutiaeButton = uibutton(app.UIFigure, 'push');
            app.MinutiaeButton.ButtonPushedFcn = createCallbackFcn(app, @minut, true);
            app.MinutiaeButton.Text = 'Minutiae';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @result, true);
            app.ShowResultButton.Text = 'Show Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Fingerprint Recognition';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end